classdef raicesDeEcuaciones < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        principalPanel                  matlab.ui.container.Panel
        RacesdeEcuacionesLabel          matlab.ui.control.Label
        empezarB                        matlab.ui.control.Button
        salirB                          matlab.ui.control.Button
        CreadoresLabel                  matlab.ui.control.Label
        GuiaButton                      matlab.ui.control.Button
        menu1Panel                      matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        titulo_menu1                    matlab.ui.control.Label
        homeB_menu1                     matlab.ui.control.Button
        calculadoraB_menu1              matlab.ui.control.Button
        evaluacionB_menu1               matlab.ui.control.Button
        ejerciciosB_menu1               matlab.ui.control.Button
        teoriaB_menu1                   matlab.ui.control.Button
        AcercadeButton                  matlab.ui.control.Button
        teoriaPanel                     matlab.ui.container.Panel
        TabGroup3                       matlab.ui.container.TabGroup
        MtododeBiseccinTab              matlab.ui.container.Tab
        MtodocerradodeBiseccinLabel     matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        MtododeRegulaFalsiTab           matlab.ui.container.Tab
        MtodocerradodeRegulaFalsiLabel  matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Image3                          matlab.ui.control.Image
        Image4                          matlab.ui.control.Image
        MtodoPuntoFijoTab               matlab.ui.container.Tab
        MtodoabiertodePuntoFijoLabel    matlab.ui.control.Label
        Image5                          matlab.ui.control.Image
        Image6                          matlab.ui.control.Image
        TextArea_3                      matlab.ui.control.TextArea
        MtodoNewtonRaphsonTab           matlab.ui.container.Tab
        MtodoabiertodeNewtonRaphsonLabel  matlab.ui.control.Label
        Image7                          matlab.ui.control.Image
        Image8                          matlab.ui.control.Image
        TextArea_4                      matlab.ui.control.TextArea
        MtododelaSecanteTab             matlab.ui.container.Tab
        MtodoabiertodelaSecanteLabel    matlab.ui.control.Label
        Image9                          matlab.ui.control.Image
        Image10                         matlab.ui.control.Image
        TextArea_5                      matlab.ui.control.TextArea
        Image38                         matlab.ui.control.Image
        TextArea_6                      matlab.ui.control.TextArea
        TextArea_7                      matlab.ui.control.TextArea
        MtododeRacesMltiplesTab         matlab.ui.container.Tab
        MtodoabiertodeRacesMltiplesLabel  matlab.ui.control.Label
        Image9_2                        matlab.ui.control.Image
        Image10_2                       matlab.ui.control.Image
        TextArea_8                      matlab.ui.control.TextArea
        Image39                         matlab.ui.control.Image
        MtodosCerradosButton            matlab.ui.control.Button
        MtodosAbiertosButton            matlab.ui.control.Button
        Panel_6                         matlab.ui.container.Panel
        calculadoraPanel                matlab.ui.container.Panel
        TecladoPanel                    matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        VariableXButton                 matlab.ui.control.Button
        LimpiarButton                   matlab.ui.control.Button
        LimpTodoButton                  matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        Button_3                        matlab.ui.control.Button
        Button_4                        matlab.ui.control.Button
        Button_5                        matlab.ui.control.Button
        Button_6                        matlab.ui.control.Button
        Button_7                        matlab.ui.control.Button
        Button_8                        matlab.ui.control.Button
        Button_9                        matlab.ui.control.Button
        Button_10                       matlab.ui.control.Button
        Button_11                       matlab.ui.control.Button
        Button_12                       matlab.ui.control.Button
        Button_13                       matlab.ui.control.Button
        SenButton                       matlab.ui.control.Button
        CosButton                       matlab.ui.control.Button
        TanButton                       matlab.ui.control.Button
        Button_14                       matlab.ui.control.Button
        Button_15                       matlab.ui.control.Button
        eButton                         matlab.ui.control.Button
        lnButton                        matlab.ui.control.Button
        RaiznButton                     matlab.ui.control.Button
        PotnButton                      matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        Panel_2                         matlab.ui.container.Panel
        IngreseFuncinLabel              matlab.ui.control.Label
        FxEditFieldLabel                matlab.ui.control.Label
        Funcion                         matlab.ui.control.EditField
        IntInferiorEditFieldLabel       matlab.ui.control.Label
        IntInferiorEditField            matlab.ui.control.NumericEditField
        IntSuperiorEditFieldLabel       matlab.ui.control.Label
        IntSuperiorEditField            matlab.ui.control.NumericEditField
        Panel_3                         matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        GraficarButton                  matlab.ui.control.Button
        LimpiarButton_2                 matlab.ui.control.Button
        Panel_4                         matlab.ui.container.Panel
        MetAbiertosDropDownLabel        matlab.ui.control.Label
        MetAbiertosDropDown             matlab.ui.control.DropDown
        MetCerradosDropDownLabel        matlab.ui.control.Label
        MetCerradosDropDown             matlab.ui.control.DropDown
        MtodosNumricosLabel             matlab.ui.control.Label
        LimInferiorEditFieldLabel       matlab.ui.control.Label
        LimInferiorEditField            matlab.ui.control.NumericEditField
        LimSuperiorEditFieldLabel       matlab.ui.control.Label
        LimSuperiorEditField            matlab.ui.control.NumericEditField
        ErrorTEditFieldLabel            matlab.ui.control.Label
        ErrorTEditField                 matlab.ui.control.NumericEditField
        ResolverButton                  matlab.ui.control.Button
        SolucionLabel                   matlab.ui.control.Label
        Solucion                        matlab.ui.control.EditField
        UIAxes                          matlab.ui.control.UIAxes
        tableRes                        matlab.ui.control.Table
        Panel_5                         matlab.ui.container.Panel
        RutaEditFieldLabel              matlab.ui.control.Label
        RutaEditField                   matlab.ui.control.EditField
        NombreEditFieldLabel            matlab.ui.control.Label
        NombreEditField                 matlab.ui.control.EditField
        ExtensinButtonGroup             matlab.ui.container.ButtonGroup
        csvButton                       matlab.ui.control.RadioButton
        xlsxButton                      matlab.ui.control.RadioButton
        txtButton                       matlab.ui.control.RadioButton
        ExportarButton                  matlab.ui.control.Button
        ejerciciosPanel                 matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        TabGroup2                       matlab.ui.container.TabGroup
        MtododeBiseccinTab_2            matlab.ui.container.Tab
        tituloBiseccion_ejercicios      matlab.ui.control.Label
        Image11                         matlab.ui.control.Image
        Image12                         matlab.ui.control.Image
        Image13                         matlab.ui.control.Image
        Image14                         matlab.ui.control.Image
        MtododeRegulaFalsiTab_2         matlab.ui.container.Tab
        tituloRegula_ejercicios         matlab.ui.control.Label
        Image15                         matlab.ui.control.Image
        Image16                         matlab.ui.control.Image
        Image17                         matlab.ui.control.Image
        Image18                         matlab.ui.control.Image
        Image19                         matlab.ui.control.Image
        MtodoPuntoFijoTab_2             matlab.ui.container.Tab
        tituloPunto_ejercicios          matlab.ui.control.Label
        Image20                         matlab.ui.control.Image
        Image21                         matlab.ui.control.Image
        Image22                         matlab.ui.control.Image
        Image23                         matlab.ui.control.Image
        Image24                         matlab.ui.control.Image
        MtodoNewtonRaphsonTab_2         matlab.ui.container.Tab
        tituloNewton_ejercicios         matlab.ui.control.Label
        Image34                         matlab.ui.control.Image
        Image35                         matlab.ui.control.Image
        Image36                         matlab.ui.control.Image
        Image37                         matlab.ui.control.Image
        MtododelaSecanteTab_2           matlab.ui.container.Tab
        tituloSecante_ejercicios        matlab.ui.control.Label
        Image25                         matlab.ui.control.Image
        Image26                         matlab.ui.control.Image
        Image27                         matlab.ui.control.Image
        Image28                         matlab.ui.control.Image
        MtododeRacesMltiplesTab_2       matlab.ui.container.Tab
        tituloRaices_ejercicios         matlab.ui.control.Label
        Image29                         matlab.ui.control.Image
        Image30                         matlab.ui.control.Image
        Image31                         matlab.ui.control.Image
        Image32                         matlab.ui.control.Image
        Image33                         matlab.ui.control.Image
        MtodosCerradosLabel_2           matlab.ui.control.Label
        MtodosAbiertosLabel_2           matlab.ui.control.Label
        evaluacionPanel                 matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        evaluacionHere                  matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        evaluarB_evaluacion             matlab.ui.control.Button
        notaEvaluacion                  matlab.ui.control.Label
        SaberMas                        matlab.ui.container.Panel
        TextArea_5Label                 matlab.ui.control.Label
        SOBRELAAPLICACINLabel           matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        TextArea_2                      matlab.ui.control.TextArea
        CREADORESLabel                  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: empezarB
        function empezarBButtonPushed(app, event)
            app.principalPanel.Visible = 'off';    %apagar el principal
            app.menu1Panel.Visible = 'on';         %encender el menu
            app.teoriaPanel.Visible = 'on';        %encender el panel de teoria
            
        end

        % Button pushed function: salirB
        function salirBButtonPushed(app, event)
            app.delete;
        end

        % Callback function
        function salirB_teoriaButtonPushed(app, event)
            %dont use
        end

        % Button pushed function: homeB_menu1
        function homeB_menu1ButtonPushed(app, event)
            app.principalPanel.Visible = 'on';
            app.menu1Panel.Visible = 'off';
            
            
            %apagar todas las demas ventaas here
            app.teoriaPanel.Visible = "off";           %mantener el panel de teoria off
            app.ejerciciosPanel.Visible = 'off';       %mantener el panel de calculadora off
            app.calculadoraPanel.Visible = 'off';      %mantener el panel de calculadora off
            app.evaluacionPanel.Visible = 'off';      %mantener el panel de calculadora off
            app.SaberMas.Visible = 'off';   
            app.teoriaB_menu1.Enable = "on";
            app.ejerciciosB_menu1.Enable = "on";
            app.evaluacionB_menu1.Enable = "on";
            app.calculadoraB_menu1.Enable = "on";
            app.AcercadeButton.Enable = "on";
                     %mantener el panel de Saber Mas off
        end

        % Button pushed function: teoriaB_menu1
        function teoriaB_menu1ButtonPushed(app, event)
            
            app.titulo_menu1.Text = "Teoría";         %cambiar el nombre del titulo
            
            app.principalPanel.Visible = 'off';
            app.menu1Panel.Visible = 'on';            %mantener encendido el menu
            app.teoriaPanel.Visible = "on";           %mantener el panel de teoria on
            
            %apagar todas las demas ventaas here
            app.ejerciciosPanel.Visible = "off";       %mantener el panel de teoria off
            app.calculadoraPanel.Visible = 'off';      %mantener el panel de calculadora off
            
            app.Image38.Position=[60 60 1000 700];
            %app.Image13.Position=[60 760 1000 700];
            %app.Image12.Position=[60 1460 1000 700];
            %app.Image11.Position=[60 2160 1000 700];
            
        end

        % Button pushed function: ejerciciosB_menu1
        function ejerciciosB_menu1ButtonPushed(app, event)
            
            app.titulo_menu1.Text = "Ejercicios";     %cambiar el nombre del titulo
            
            app.principalPanel.Visible = 'off';
            app.menu1Panel.Visible = 'on';            %mantener encendido el menu
            app.ejerciciosPanel.Visible = 'on';       %mantener encendido el de ejercicios
            
            app.teoriaPanel.Visible = "off";          %mantener el panel de teoria off
            app.calculadoraPanel.Visible = 'off';      %mantener el panel de calculadora off
            app.evaluacionPanel.Visible = 'off';      %mantener el panel de ejercicios off
            app.SaberMas.Visible = 'off';            %mantener el panel de Saber Mas off
            app.Image14.Position=[60 60 1000 700];
            app.Image13.Position=[60 760 1000 700];
            app.Image12.Position=[60 1460 1000 700];
            app.Image11.Position=[60 2160 1000 700];
            

        end

        % Button pushed function: calculadoraB_menu1
        function calculadoraB_menu1ButtonPushed(app, event)
            
            app.titulo_menu1.Text = "Calculadora";    %cambiar el nombre del titulo
            
            app.principalPanel.Visible = 'off';
            app.calculadoraPanel.Visible = 'on';      %mantener encendido la calculadora
            
            app.teoriaPanel.Visible = "off";          %mantener el panel de teoria off
            app.ejerciciosPanel.Visible = 'off';      %mantener el panel de ejercicios off
            app.evaluacionPanel.Visible = 'off';      %mantener el panel de ejercicios off
            app.SaberMas.Visible = 'off';            %mantener el panel de Saber Mas off
        end

        % Button pushed function: VariableXButton
        function VariableXButtonPushed(app, event)
            cat = app.Funcion.Value; 
            app.Funcion.Value=strcat(cat,"x");
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            cat = app.Funcion.Value; 
            app.Funcion.Value=strcat(cat,"1");
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            cat = app.Funcion.Value; 
            app.Funcion.Value=strcat(cat,"2");
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            cat = app.Funcion.Value; 
            app.Funcion.Value=strcat(cat,"3");
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            cat = app.Funcion.Value; 
            app.Funcion.Value=strcat(cat,"+");
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            cat = app.Funcion.Value; 
            app.Funcion.Value=strcat(cat,"-");
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            cat = app.Funcion.Value; 
            app.Funcion.Value=strcat(cat,"4");
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            cat = app.Funcion.Value; 
            app.Funcion.Value=strcat(cat,"5");
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            cat = app.Funcion.Value; 
            app.Funcion.Value=strcat(cat,"6");
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            cat = app.Funcion.Value; 
            app.Funcion.Value=strcat(cat,".*");
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            cat = app.Funcion.Value; 
            app.Funcion.Value=strcat(cat,"7");
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            cat = app.Funcion.Value; 
            app.Funcion.Value=strcat(cat,"8");
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            cat = app.Funcion.Value; 
            app.Funcion.Value=strcat(cat,"9");
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            cat = app.Funcion.Value; 
            app.Funcion.Value=strcat(cat,"./");
        end

        % Button pushed function: SenButton
        function SenButtonPushed(app, event)
            cat = app.Funcion.Value; 
            app.Funcion.Value=strcat(cat,'sin(');
        end

        % Button pushed function: CosButton
        function CosButtonPushed(app, event)
            cat = app.Funcion.Value; 
            app.Funcion.Value=strcat(cat,'cos(');
        end

        % Button pushed function: TanButton
        function TanButtonPushed(app, event)
            cat = app.Funcion.Value; 
            app.Funcion.Value=strcat(cat,'tan(');
        end

        % Button pushed function: Button_14
        function Button_14Pushed(app, event)
             cat = app.Funcion.Value; 
            app.Funcion.Value=strcat(cat,'(');
        end

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
             cat = app.Funcion.Value; 
            app.Funcion.Value=strcat(cat,')');
        end

        % Button pushed function: eButton
        function eButtonPushed(app, event)
              cat = app.Funcion.Value; 
            app.Funcion.Value=strcat(cat,'exp(');
        end

        % Button pushed function: lnButton
        function lnButtonPushed(app, event)
              cat = app.Funcion.Value; 
            app.Funcion.Value=strcat(cat,'ln(');
        end

        % Button pushed function: RaiznButton
        function RaiznButtonPushed(app, event)
            cat = app.Funcion.Value; 
            app.Funcion.Value=strcat(cat,'.^(1/');
        end

        % Button pushed function: PotnButton
        function PotnButtonPushed(app, event)
            cat = app.Funcion.Value; 
            app.Funcion.Value=strcat(cat,'.^');
        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
            ini = char (' ');
            app.Funcion.Value=strcat(ini);
        end

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            funcion = app.Funcion.Value;
            intervalo = [app.IntInferiorEditField.Value app.IntSuperiorEditField.Value];
                       
            if ~isequal('',funcion)
                fplot(app.UIAxes,funcion,intervalo);
            end
            
        end

        % Button pushed function: ResolverButton
        function ResolverButtonPushed(app, event)
            funcion = app.Funcion.Value;
            limiInf = app.LimInferiorEditField.Value;
            limiSup = app.LimSuperiorEditField.Value;
            tole   = app.ErrorTEditField.Value;
            metAbier = app.MetAbiertosDropDown.Value;
            metCerr  = app.MetCerradosDropDown.Value;
            
            if isequal('',metCerr) && ~isequal('',metAbier)
                
                if isequal('Punto Fijo', metAbier) %punto fijo
                    limError = 0.00001;
                    pIni = limiInf;
                    syms x;
                    iniError = 100;
                    fdev = str2sym(fun);
                    deriv = diff(fdev,x);
                    deriv = inline(deriv);
                    it =0;
                    
                    v= [v;it pIni f(pIni) deriv(pIni) iniError];
                    
                    if abs(deriv(izq)==0 )
                        msgbox ('No converge, Error');
                        return;
                    else
                        while iniError > limError
                            xi = pIni - (f(pIni)/deriv(pIni));
                            iniError = abs (((xi-pIni)/xi)*100);
                            pIni = xi;
                            it = it+1;
                            v= [v; it pIni f(pIni) deriv(pIni) iniError]; %matriz de valores%
                        end
                    end
                    
                    app.Solucion.Value = pIni;
                elseif isequal('Newton-Raphson', metAbier) %Newton
                    syms x;
                    x0 = limiInf;
                    f = inline(funcion);
                    dxf = inline(diff(str2sym(funcion),x));
                    N=2;
                    tol = tole; %field numeric
                    maxDiv = 10000;
                    x =x0;
                    tab = {'i','x','f(x)','n(x)','error'};
                    while (N<10000)
                        xn = x -f(x)/dxf(x );
                        tab(N,:) = {N-1,x,f(x),xn,abs(f(x))};
                        if abs(f(xn))<tol
                            x =xn;
                            iter=100-N;
                            app.Solucion.Value = num2str(x);
                            app.tableRes.Data = tab;
                            drawnow;
                            break;
                        end
                        if abs(f(x ))>maxDiv
                            app.Solucion.Value='Se alcanzo el numero maximo de iteraciones';
                            app.tableRes.Data = tab;
                            drawnow;
                            break;
                        end
                        N = N + 1;
                        x = xn;
                    end
        if N == 10000
         app.Solucion.Value='La solucion no converge';
        end
                elseif isequal('Secante', metAbier) %Secante
                    fun = inline(funcion);
                    a = limiInf; %field numeric
                    b = limiSup; %field numeric
                    tol = tole;
                    
                    u = fun(a);
                    v = fun(b);
                    c=2;
                    p0=a;
                    p1=b;
                    
                    %f=uifigure;
                    tab = {'i','x','x-1','x+1','f(x)','f(x-1)','error'};
                    
                    while abs(u) > tol
                        tab(c,:) = {c,p0,p1,(p0-u*(p1-p0)/(v-u)),u,v,abs(u)};
                        p=p0-u*(p1-p0)/(v-u);
                        p0=p;
                        p1=b;
                        u=fun(p0);
                        v=fun(p1);
                        c=c+1;
                    end
                    app.Solucion.Value = num2str(p0);
                    app.tableRes.Data = tab;

                    
                elseif isequal('Raic. Multiples', metAbier) %Raic Multiple
                    app.Solucion.Value = "raic";
                end
                           
            elseif ~isequal('',metCerr) && isequal('',metAbier)
                
                if isequal('Bisección',metCerr) %biseccion
                    fun = inline(funcion);
                    a = limiInf;
                    b = limiSup;
                    tol = tole;
                    tab = {'a','b','m','f(a)','f(b)','f(m)','error'};
                    %uitable
                    if a>b
                        aa=a ;
                        a=b ;
                        b=aa ;
                    end 
                    xm=(a+b ) / 2 ;
                    if fun ( a ) *fun ( b )>0 
                        app.Solucion.Value='ERROR: Revise el intervalo';
                        drawnow;
                    elseif abs (b - a )<tol
                        raiz=xm ;
                    else
                        cont=2;
                        while ( ( fun ( xm ) ~=0)&& abs (b - a )>tol )
                            if fun ( xm ) *fun ( b )<0
                                a=xm ;
                            else
                                b=xm ;
                            end 
                            xm=(a+b ) / 2 ;
                            tab(cont,:) = {a,b,xm,fun(a),fun(b),fun(b),abs(b-a)};
                            cont = cont+1;
                        end
                        raiz=xm ;
                    end
                    app.Solucion.Value= num2str(raiz);
                    app.tableRes.Data = tab;
                    %set(handles.uitable,'Data',tab); %tabla
                    drawnow;
                    
                    
                elseif isequal('Regla Falsa',metCerr) %Regla falsa
                     Fx = funcion;
                     a = limiInf;
                     c = limiSup;
                     e = tole;
                     
                     x=a;
                     Fa=eval(Fx);
                     x=c;
                     Fc=eval(Fx);
                     cont=1;
                     tab = {'i','a','b','f(a)','f(b)','c','f(c)','error'};
                     while abs(c-a)>e
                         cont = cont+1;
                         b=(c*Fa-a*Fc)/(Fa-Fc);
                         x=b;
                         Fb=eval(Fx);
                         tab(cont,:) = {cont,a,b,Fa,Fb,c,Fc,abs(c-a)};
                         if abs(Fc)<e
                             break;
                         else    
                             if Fa*Fb<=0
                                 c=b;
                                 Fc=Fb;
                             else
                                 a=b;
                                 Fa=Fb;
                             end
                         end
                     end
                     app.Solucion.Value= num2str(b);
                     app.tableRes.Data = tab;
                     drawnow;
                end
            end          


        end

        % Button pushed function: ExportarButton
        function ExportarButtonPushed(app, event)
            ruta = app.RutaEditField.Value;
            nombre = app.NombreEditField.Value;
            extension = app.ExtensinButtonGroup.SelectedObject.Text;
            
            ruteFull = strcat(ruta,'\',nombre,extension);
            data = app.tableRes.Data;
            
            switch(extension)
                case '.csv'
                    writematrix(string(data),ruteFull);
                case '.xlsx'
                    xlswrite(ruteFull,data);
                case '.txt'
                    writecell(data,ruteFull);
            otherwise
                    
            end
        end

        % Button pushed function: evaluacionB_menu1
        function evaluacionB_menu1ButtonPushed(app, event)
            app.principalPanel.Visible = 'off';    %apagar el principal
            app.menu1Panel.Visible = 'on';         %encender el menu
            app.evaluacionPanel.Visible = 'on';        %encender el panel de evaluacion
            
            app.titulo_menu1.Text = "Evaluacion";         %cambiar el nombre del titulo
            app.SaberMas.Visible = 'off';            %mantener el panel de Saber Mas off
            app.notaEvaluacion.Visible = "off";
            %apagar los demas paneles
            app.teoriaPanel.Visible = 'off';   
            app.calculadoraPanel.Visible = 'off';    
            app.ejerciciosPanel.Visible = 'off';    
            
            %desactivr los botones, excepto menu y evaluar
            app.teoriaB_menu1.Enable = "off";
            app.ejerciciosB_menu1.Enable = "off";
            app.evaluacionB_menu1.Enable = "off";
            app.calculadoraB_menu1.Enable = "off";
            app.AcercadeButton.Enable="off";
            %creando evaluacion
            fig = app.evaluacionHere;
            
            preg = readtable("evaluacion.csv");
            preg1= readtable("evaluacion1.csv");
            
            %agregando las preguntas a la ui
            itPosY = 1800;
            
            %preguntas para resolver
            for i = 1:1:5
                lbl = uilabel(fig);
                lbl.Text = strcat(string(preg{i,1}), '.- ' ,string(preg{i,2}));  %pregunta
                lbl.FontSize = 18;
                lbl.Position = [13 itPosY 1220 100];
                itPosY = itPosY-120;
                
                opcS = string(preg{i,4});
                opc = regexp(opcS,',','split');
                
                bg = uibuttongroup(fig,'Position',[13 itPosY+20 1000 125]);
                bg.Tag = strcat('preg',num2str(i));
                rb1 = uiradiobutton(bg,'Position',[10 104 91 15],'HandleVisibility','on');
                rb2 = uiradiobutton(bg,'Position',[10 82 91 15],'HandleVisibility','on');
                rb3 = uiradiobutton(bg,'Position',[10 60 91 15],'HandleVisibility','on');
                rb4 = uiradiobutton(bg,'Position',[10 38 91 15],'HandleVisibility','on');
                rb5 = uiradiobutton(bg,'Position',[10 16 91 15],'HandleVisibility','on');
                
                rb1.Text = opc(1);
                rb2.Text = opc(2);
                rb3.Text = opc(3);
                rb4.Text = opc(4);
                rb5.Text = opc(5);
                
                itPosY = itPosY-60;
            end
            
            %preguntas de teoria
            for i = 1:1:5
                lbl = uilabel(fig);
                lbl.Text = strcat(string(i+5), '.- ' ,string(preg1{i,2}));
                lbl.FontSize = 18;
                lbl.Position = [13 itPosY 1220 100];
                itPosY = itPosY-120;
                
                opcS = string(preg1{i,4});
                opc = regexp(opcS,',','split');
                
                bg = uibuttongroup(fig,'Position',[13 itPosY+20 1000 125]);
                bg.Tag = strcat('preg',num2str(i+5));
                rb1 = uiradiobutton(bg,'Position',[10 104 500 15],'HandleVisibility','on');
                rb2 = uiradiobutton(bg,'Position',[10 82 500 15],'HandleVisibility','on');
                rb3 = uiradiobutton(bg,'Position',[10 60 500 15],'HandleVisibility','on');
                rb4 = uiradiobutton(bg,'Position',[10 38 500 15],'HandleVisibility','on');
                rb5 = uiradiobutton(bg,'Position',[10 16 500 15],'HandleVisibility','on');
                
                rb1.Text = opc(1);
                rb2.Text = opc(2);
                rb3.Text = opc(3);
                rb4.Text = opc(4);
                rb5.Text = opc(5);
                
                itPosY = itPosY-60;
            end

        end

        % Button pushed function: evaluarB_evaluacion
        function evaluarB_evaluacionButtonPushed(app, event)
            
            %activar los botones
            app.teoriaB_menu1.Enable = "on";
            app.ejerciciosB_menu1.Enable = "on";
            app.evaluacionB_menu1.Enable = "on";
            app.calculadoraB_menu1.Enable = "on";
            app.AcercadeButton.Enable= "on";
            preg = readtable("evaluacion.csv");
            preg1= readtable("evaluacion1.csv");
            
            respC1 = str2double(string(preg{:,5}));
            respC2 = string(preg1{:,5});
            
            %obtendiendo primeras 12 respuestas
            for i = 1:1:5
                grupo = findobj(app.evaluacionHere,'Tag',strcat('preg',num2str(i)));
                respU1(i,1) =  str2double(string(grupo.SelectedObject.Text));   
            end
            
            %obtendiendo 8 respuestas restantes
            for i = 6:1:10
                grupo = findobj(app.evaluacionHere,'Tag',strcat('preg',num2str(i)));
                respU2(i-5,1) =  string(grupo.SelectedObject.Text);   
            end
            
            fig = app.evaluacionHere;
            itPosY = 1800;
            
            correctas = 0;
            %comparando data
            for i = 1:1:5
                if (respC1(i)==respU1(i))
                    lbl = uilabel(fig,'BackgroundColor','Green',"FontColor",'White');
                    lbl.Text = 'Correcta';
                    lbl.FontSize = 18;
                    lbl.Position = [800 itPosY-20 100 50];
                    correctas = correctas+1;
                else
                    lbl = uilabel(fig,'BackgroundColor','Red',"FontColor",'White');
                    lbl.Text = 'Incorrecta';
                    lbl.FontSize = 18;
                    lbl.Position = [800 itPosY-20 100 50];
                end
                
                itPosY = itPosY-180;
            end
            
            for i = 1:1:5
                if (respC2(i)==respU2(i))
                    lbl = uilabel(fig,'BackgroundColor','Green',"FontColor",'White');
                    lbl.Text = 'Correcta';
                    lbl.FontSize = 18;
                    lbl.Position = [800 itPosY-20 100 50];
                    correctas = correctas+1;
                else
                    lbl = uilabel(fig,'BackgroundColor','Red',"FontColor",'White');
                    lbl.Text = 'Incorrecta';
                    lbl.FontSize = 18;
                    lbl.Position = [800 itPosY-20 100 50];
                end
                
                itPosY = itPosY-180;
            end
            
            app.notaEvaluacion.Visible = "on";
            app.notaEvaluacion.Text = strcat('Nota: ',num2str(correctas));
        end

        % Size changed function: SaberMas
        function SaberMasSizeChanged(app, event)
            position = app.SaberMas.Position;
            
        end

        % Button pushed function: AcercadeButton
        function AcercadeButtonPushed(app, event)
            app.titulo_menu1.Text = "Sobre la Aplicacion"; 
            app.principalPanel.Visible = 'off';
            app.SaberMas.Visible = 'on';      %mantener encendido la calculadora
            app.teoriaPanel.Visible = "off";          %mantener el panel de teoria off
            app.ejerciciosPanel.Visible = 'off';      %mantener el panel de ejercicios off
            app.evaluacionPanel.Visible = 'off';      %mantener el panel de ejercicios off 
        end

        % Button pushed function: GuiaButton
        function GuiaButtonPushed(app, event)
          winopen("GuiaMN.pdf")
        end

        % Selection change function: TabGroup2
        function TabGroup2SelectionChanged(app, event)
            selectedTab = app.TabGroup2.SelectedTab;
            app.Image14.Position=[60 60 1000 700];
            app.Image13.Position=[60 760 1000 700];
            app.Image12.Position=[60 1460 1000 700];
            app.Image11.Position=[60 2160 1000 700];
            app.Image15.Position=[60 60 1000 700];
            app.Image16.Position=[60 760 1000 700];
            app.Image17.Position=[60 1460 1000 700];
            app.Image18.Position=[60 2160 1000 700];
            app.Image19.Position=[60 2860 1000 700];
            app.Image20.Position=[60 60 1000 700];
            app.Image21.Position=[60 760 1000 700];
            app.Image22.Position=[60 1460 1000 700];
            app.Image23.Position=[60 2160 1000 700];
            app.Image24.Position=[60 2860 1000 700];
            app.Image28.Position=[60 60 1000 700];
            app.Image27.Position=[60 760 1000 700];
            app.Image26.Position=[60 1460 1000 700];
            app.Image25.Position=[60 2160 1000 700];
            app.Image33.Position=[60 60 1000 700];
            app.Image32.Position=[60 760 1000 700];
            app.Image31.Position=[60 1460 1000 700];
            app.Image30.Position=[60 2160 1000 700];
            app.Image29.Position=[60 2860 1000 700];
            app.Image37.Position=[60 60 1000 700];
            app.Image36.Position=[60 760 1000 700];
            app.Image35.Position=[60 1460 1000 700];
            app.Image34.Position=[60 2160 1000 700];
        end

        % Size changed function: MtododeBiseccinTab_2
        function MtododeBiseccinTab_2SizeChanged(app, event)
            position = app.MtododeBiseccinTab_2.Position;
            fig = app.MtododeBiseccionTab_2;
            f = uifigure;
            im = uiimage(f);
            im.ImageSource = '../Ejercicios/0001.jpg';
            im.Position=[60 60 1000 700];
            
        end

        % Button pushed function: MtodosAbiertosButton
        function MtodosAbiertosButtonPushed(app, event)
            app.Panel_6.Position = [6 523 404 30];
        end

        % Button pushed function: MtodosCerradosButton
        function MtodosCerradosButtonPushed(app, event)
            app.Panel_6.Position = [409 523 868 30];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.principalPanel = uipanel(app.UIFigure);
            app.principalPanel.Tag = 'principal';
            app.RacesdeEcuacionesLabel = uilabel(app.principalPanel);
            app.RacesdeEcuacionesLabel.Text = {'Raíces de '; 'Ecuaciones'};
            app.empezarB = uibutton(app.principalPanel, 'push');
            app.empezarB.ButtonPushedFcn = createCallbackFcn(app, @empezarBButtonPushed, true);
            app.empezarB.Tag = 'empezarB';
            app.empezarB.Text = 'Empezar';
            app.salirB = uibutton(app.principalPanel, 'push');
            app.salirB.ButtonPushedFcn = createCallbackFcn(app, @salirBButtonPushed, true);
            app.salirB.Text = 'Salir';
            app.CreadoresLabel = uilabel(app.principalPanel);
            app.CreadoresLabel.Text = {'Creadores:'; '  Sergio Yhoel Soncco Cahui.  '; '  Jhonatan Acuña Huisacayna'; '  Bryan Gallegos Batallanos'; '  Frank Ccapa Usca'; '  Luis Florez Bailon'};
            app.GuiaButton = uibutton(app.principalPanel, 'push');
            app.GuiaButton.ButtonPushedFcn = createCallbackFcn(app, @GuiaButtonPushed, true);
            app.GuiaButton.Text = 'Guia';
            app.menu1Panel = uipanel(app.UIFigure);
            app.menu1Panel.Visible = 'off';
            app.menu1Panel.Tag = 'menu1';
            app.GridLayout3 = uigridlayout(app.menu1Panel);
            app.titulo_menu1 = uilabel(app.GridLayout3);
            app.titulo_menu1.Tag = 'titulo_menu1';
            app.titulo_menu1.Text = 'Teoría';
            app.homeB_menu1 = uibutton(app.GridLayout3, 'push');
            app.homeB_menu1.ButtonPushedFcn = createCallbackFcn(app, @homeB_menu1ButtonPushed, true);
            app.homeB_menu1.Tag = 'homeB_menu1';
            app.homeB_menu1.Text = 'Home';
            app.calculadoraB_menu1 = uibutton(app.GridLayout3, 'push');
            app.calculadoraB_menu1.ButtonPushedFcn = createCallbackFcn(app, @calculadoraB_menu1ButtonPushed, true);
            app.calculadoraB_menu1.Tag = 'calculadoraB_menu1';
            app.calculadoraB_menu1.Text = 'Calculadora';
            app.evaluacionB_menu1 = uibutton(app.GridLayout3, 'push');
            app.evaluacionB_menu1.ButtonPushedFcn = createCallbackFcn(app, @evaluacionB_menu1ButtonPushed, true);
            app.evaluacionB_menu1.Tag = 'evaluacionB_menu1';
            app.evaluacionB_menu1.Text = 'Evaluación';
            app.ejerciciosB_menu1 = uibutton(app.GridLayout3, 'push');
            app.ejerciciosB_menu1.ButtonPushedFcn = createCallbackFcn(app, @ejerciciosB_menu1ButtonPushed, true);
            app.ejerciciosB_menu1.Tag = 'ejerciciosB_menu1';
            app.ejerciciosB_menu1.Text = 'Ejercicios';
            app.teoriaB_menu1 = uibutton(app.GridLayout3, 'push');
            app.teoriaB_menu1.ButtonPushedFcn = createCallbackFcn(app, @teoriaB_menu1ButtonPushed, true);
            app.teoriaB_menu1.Tag = 'teoriaB_menu1';
            app.teoriaB_menu1.Text = 'Teoría';
            app.AcercadeButton = uibutton(app.GridLayout3, 'push');
            app.AcercadeButton.ButtonPushedFcn = createCallbackFcn(app, @AcercadeButtonPushed, true);
            app.AcercadeButton.Text = 'Acerca de';
            app.teoriaPanel = uipanel(app.UIFigure);
            app.teoriaPanel.Visible = 'off';
            app.TabGroup3 = uitabgroup(app.teoriaPanel);
            app.MtododeBiseccinTab = uitab(app.TabGroup3);
            app.MtododeBiseccinTab.Title = '        Método de Bisección        ';
            app.MtodocerradodeBiseccinLabel = uilabel(app.MtododeBiseccinTab);
            app.MtodocerradodeBiseccinLabel.Text = 'Método cerrado de Bisección';
            app.Label = uilabel(app.MtododeBiseccinTab);
            app.Label.Text = {'Este es uno de los métodos más sencillos y de fácil intuición para resolver ecuaciones de una variable, también conocido '; 'como Método de Intervalo Medio.1​ Se basa en el teorema del valor intermedio (TVI), el cual establece que toda función '; 'continua f en un intervalo cerrado [a,b] toma todos los valores que se hallan entre f(a) y f(b). Esto es que todo valor entre '; 'f(a) y f(b) es la imagen de al menos un valor en el intervalo [a,b]. En caso de que '; 'f(a) y f(b) tengan signos opuestos, el valor cero sería un valor intermedio entre '; 'f(a) y f(b), por lo que con certeza existe un p en [a,b] que cumple f(p)=0. '; 'De esta forma, se asegura la existencia de al menos una solución de la ecuación '; 'f(x)=0.'};
            app.Image2 = uiimage(app.MtododeBiseccinTab);
            app.Image2.ImageSource = 'bis2.PNG';
            app.MtododeRegulaFalsiTab = uitab(app.TabGroup3);
            app.MtododeRegulaFalsiTab.Title = '        Método de Regula Falsi        ';
            app.MtodocerradodeRegulaFalsiLabel = uilabel(app.MtododeRegulaFalsiTab);
            app.MtodocerradodeRegulaFalsiLabel.Text = 'Método cerrado de Regula Falsi';
            app.Label_2 = uilabel(app.MtododeRegulaFalsiTab);
            app.Label_2.Text = {'El método de regla falsa, también conocido como regula falsi, es un método iterativo que a diferencia de bisección, que '; 'busca el punto medio del intervalo, une por medio de una línea recta las imágenes de la función en los dos extremos del '; 'intervalo (f(a) y f(b)). La intersección de esta nueva recta con el eje x representa una mejor estimación de la raíz de la '; 'función.'; ' '; 'La función en el intervalo hallado por método de búsquedas '; 'debe ser continua y cumplir que f(a) × f(b) < 0.'};
            app.Image3 = uiimage(app.MtododeRegulaFalsiTab);
            app.Image3.ImageSource = 'regula.PNG';
            app.Image4 = uiimage(app.MtododeRegulaFalsiTab);
            app.Image4.ImageSource = 'regula2.PNG';
            app.MtodoPuntoFijoTab = uitab(app.TabGroup3);
            app.MtodoPuntoFijoTab.Title = '        Método Punto Fijo        ';
            app.MtodoabiertodePuntoFijoLabel = uilabel(app.MtodoPuntoFijoTab);
            app.MtodoabiertodePuntoFijoLabel.Text = 'Método abierto de Punto Fijo';
            app.Image5 = uiimage(app.MtodoPuntoFijoTab);
            app.Image5.ImageSource = 'punto.PNG';
            app.Image6 = uiimage(app.MtodoPuntoFijoTab);
            app.Image6.ImageSource = 'punto2.PNG';
            app.TextArea_3 = uitextarea(app.MtodoPuntoFijoTab);
            app.TextArea_3.Value = {'El método del punto fijo es un método iterativo que permite resolver sistemas de ecuaciones no necesariamente lineales. En particular se puede utilizar para determinar raíces de una función de la forma f(x), siempre y cuando se cumplan los criterios de convergencia. El método de iteración de punto fijo, también denominado método de aproximación sucesiva, requiere volver a escribir la ecuación f(x) = 0 en la forma x = g(x).'; ''; 'El método de punto fijo o de aproximaciones sucesivas es, junto con el de Bisección, uno de los primeros métodos que se utilizaron para resolver ecuaciones algebraicas y trascendentes. No obstante que en la actualidad existen otros métodos más eficientes, el de punto fijo se considera el más simple en sus principios y en él se pueden apreciar claramente todas las características de un método de aproximaciones sucesivas.'; 'Sea F(x) = 0 una ecuación algebraica o trascendente cualquiera. Se suma x en ambos miembros y se obtiene:  '; '(1)  F(x)   + x   =   x'; 'donde el miembro izquierdo es otra función de x que se define como'; '(2) G(x) +    x   = x'; 'Se sustituye en la ecuación (1):  '; '(3) x   = G(x)'; 'Obsérvese ahora que cualquier ecuación puede representarse en esta forma, siguiendo el procedimiento anterior.'; '  Si x = a es una raíz de la ecuación, entonces'; 'F (a) = 0'; 'o bien, al sustituir en la ecuación (3)'; 'a = G (a)'; 'El método de aproximaciones sucesivas consiste en sustituir un valor inicial (x0) apropiado (cercano a la raíz) en el segundo miembro de la ecuación (3). Si x0 es la raíz, se deberá cumplir la ecuación (4); esto es:'; 'x0 = G(x0)'; 'pero esto será difícil de que ocurra; seguramente el valor inicial principal proporcionado xo será solo un valor cercano a la raíz. Entonces, en el caso general:'; 'x0 =/ G(x0) o bien, x1 = G(x0)'; 'donde x1 es la nueva aproximación de la raíz a. se sustituye x1 en el segundo miembro de la ecuación (3) y se obtiene:'; 'x2 = G(x1)'; 'Al proceder reiteradamente en esta forma se induce que la n-ésima aproximación es:'; 'Xn = G(Xn-1)'; 'n = 1,2, 3, ….'; 'De acuerdo con lo visto en los temas anteriores, puede afirmarse que, si el método converge, la diferencia en valor absoluto entre valores proporcionados en dos iteraciones sucesivas será cada vez más pequeña a medida que n aumente, y con esto se tendrá un criterio para saber cuándo termina la aplicación del método.'; 'Es posible afirmar que si en la n-ésima iteración el método se está aproximando a la raíz o converge a ella, entonces:'; '|G´(t)| = |a - Xn| / |a - Xn-1| <1'; 'Es decir, el método es convergente si: '; '|G´(t)|<1                       Xn-1<t<a'; 'Esto significa que el método converge en la n-ésima iteración cuando el valor absoluto de la derivada de G(x) en cualquier punto del intervalo (Xn-1, a) es menor que la unidad.'; 'Por otra parte, el método es divergente si'; '|a - Xn| > |a - Xn-1|'; 'Recordar que el método de Punto Fijo, nos dice que, solo podrá haber y tener un único punto o raíz'; ''};
            app.MtodoNewtonRaphsonTab = uitab(app.TabGroup3);
            app.MtodoNewtonRaphsonTab.Title = '       Método Newton-Raphson      ';
            app.MtodoabiertodeNewtonRaphsonLabel = uilabel(app.MtodoNewtonRaphsonTab);
            app.MtodoabiertodeNewtonRaphsonLabel.Text = 'Método abierto de Newton - Raphson';
            app.Image7 = uiimage(app.MtodoNewtonRaphsonTab);
            app.Image7.ImageSource = 'newton.PNG';
            app.Image8 = uiimage(app.MtodoNewtonRaphsonTab);
            app.Image8.ImageSource = 'newton1.PNG';
            app.TextArea_4 = uitextarea(app.MtodoNewtonRaphsonTab);
            app.TextArea_4.Editable = 'off';
            app.TextArea_4.Value = {'El método de Newton es un método abierto, en el sentido de que no está garantizada su convergencia global. La única manera de alcanzar la convergencia es seleccionar  un valor inicial lo suficientemente cercano a la raíz buscada. Así, se ha de comenzar la iteración con un valor razonablemente cercano al cero (denominado punto de arranque o valor supuesto). La relativa cercanía del punto inicial a la raíz depende mucho de la naturaleza de la propia función; si ésta presenta múltiples puntos de inflexión o pendientes grandes en el entorno de la raíz, entonces las probabilidades de que el algoritmo diverja aumentan, lo cual exige seleccionar un valor supuesto cercano a la raíz. '; 'Una vez que se ha hecho esto, el método linealiza la función por la recta tangente en ese valor supuesto. La abscisa en el origen de dicha recta será, según el método, una mejor aproximación de la raíz que el valor anterior. Se realizarán sucesivas iteraciones hasta que el método haya convergido lo suficiente'; ''; 'CONDICIONES DE CONVERGENCIA'; 'Las condiciones de convergencia del método de Newton-Raphson pueden resumirse de la siguiente manera:'; 'Existencia de la Raíz.'; 'Dado un cierto intervalo de trabajo [a,b], dentro del mismo debe cumplirse que f(a)*f(b)<0.'; 'Unicidad de la Raíz.'; 'Dentro del intervalo de trabajo [a,b], la derivada de f(x) debe ser diferente de cero.'; 'Concavidad.'; 'La gráfica de la función f(x) dentro del intervalo de trabajo [a,b], debe ser cóncava, hacia arriba o hacia abajo. Para ello debe verificarse que:'; 'f ''''(x) <= 0   ó   f ''''(x) >= 0   para todo x que pertenezca a [a,b]'; ''; 'Intersección de la Tangente a f(x), dentro de [a,b]'; 'Se debe asegurar que la tangente a la curva en el EXTREMO del intervalo [a,b] en el cual f''(x) sea mínima, intersecte al eje x dentro del intervalo [a,b].'; 'De esta manera aseguramos que la sucesión de valores de xi caigan dentro de [a,b].'; ''; ''};
            app.MtododelaSecanteTab = uitab(app.TabGroup3);
            app.MtododelaSecanteTab.Title = '        Método de la Secante      ';
            app.MtodoabiertodelaSecanteLabel = uilabel(app.MtododelaSecanteTab);
            app.MtodoabiertodelaSecanteLabel.Text = 'Método abierto de la Secante';
            app.Image9 = uiimage(app.MtododelaSecanteTab);
            app.Image9.ImageSource = 'secante.PNG';
            app.Image10 = uiimage(app.MtododelaSecanteTab);
            app.Image10.ImageSource = 'secante2.PNG';
            app.TextArea_5 = uitextarea(app.MtododelaSecanteTab);
            app.TextArea_5.Value = {'Es una variación del método de Newton-Raphson donde en vez de calcular la derivada de la función en el punto de estudio, teniendo en mente la definición de derivada, se aproxima la pendiente a la recta que une la función evaluada en el punto de  estudio y en el punto de la iteración anterior. Este método es de especial interés cuando el coste computacional de derivar la función de estudio y evaluarla es demasiado elevado, por lo que el método de Newton no resulta atractivo. En otras palabras, el método de la secante es un algoritmo de la raíz de investigación  que utiliza una serie de raíces de las líneas secantes para aproximar mejor la raíz de una función f. El método de la secante se puede considerar como una aproximación '; 'en diferencias finitas del método de Newton-Raphson. Sin embargo, este método fue desarrollado independientemente de este último.'; ''};
            app.Image38 = uiimage(app.MtododelaSecanteTab);
            app.Image38.ImageSource = 'im1.PNG';
            app.TextArea_6 = uitextarea(app.MtododelaSecanteTab);
            app.TextArea_6.Value = {'es el número áureo, por lo que se trata de una convergencia supe lineal inferior a la del método de Newton-Raphson. En caso de que la aproximación inicial sea demasiado lejana o la raíz no sea simple, este método no asegura la convergencia y tiene un comportamiento similar al de Newton-Raphson.'};
            app.TextArea_7 = uitextarea(app.MtododelaSecanteTab);
            app.TextArea_7.Value = {'El orden de convergencia de este método, en un punto cercano a la solución, es φ donde:'};
            app.MtododeRacesMltiplesTab = uitab(app.TabGroup3);
            app.MtododeRacesMltiplesTab.Title = '        Método de Raíces Múltiples          ';
            app.MtodoabiertodeRacesMltiplesLabel = uilabel(app.MtododeRacesMltiplesTab);
            app.MtodoabiertodeRacesMltiplesLabel.Text = 'Método abierto de Raíces Múltiples';
            app.Image9_2 = uiimage(app.MtododeRacesMltiplesTab);
            app.Image9_2.ImageSource = 'raices.PNG';
            app.Image10_2 = uiimage(app.MtododeRacesMltiplesTab);
            app.Image10_2.ImageSource = 'raices2.PNG';
            app.TextArea_8 = uitextarea(app.MtododeRacesMltiplesTab);
            app.TextArea_8.Value = {'Este método es una corrección a la técnica de Newton-Raphson, dado a que éste solo garantiza la convergencia en la raíz si y solo si la derivada de la función f(x) nunca se hace cero.'; '¿Porque recibe el nombre de raíces múltiples?'; 'El método de Newton Raphson modificado permite resolver funciones no lineales que contengan raíces críticas (máximos, mínimos o puntos de inflexión que corten el eje x), es decir, lugares donde simultáneamente la función y su derivada se hagan cero.En estos puntos la raíz se repite y por ello recibe el nombre de raíz múltiple.'; '-Cuando la raíz es un punto de inflexión, la raíz se repite un número impar de veces.'; '-Cuando la raíz es un máximo o un mínimo, la raíz se repite un número par de veces.'; ''};
            app.Image39 = uiimage(app.MtododeRacesMltiplesTab);
            app.Image39.ImageSource = 'newton-modificado.jpg';
            app.MtodosCerradosButton = uibutton(app.teoriaPanel, 'push');
            app.MtodosCerradosButton.ButtonPushedFcn = createCallbackFcn(app, @MtodosCerradosButtonPushed, true);
            app.MtodosCerradosButton.Text = 'Métodos Cerrados';
            app.MtodosAbiertosButton = uibutton(app.teoriaPanel, 'push');
            app.MtodosAbiertosButton.ButtonPushedFcn = createCallbackFcn(app, @MtodosAbiertosButtonPushed, true);
            app.MtodosAbiertosButton.Text = 'Métodos Abiertos';
            app.Panel_6 = uipanel(app.teoriaPanel);
            app.calculadoraPanel = uipanel(app.UIFigure);
            app.calculadoraPanel.Visible = 'off';
            app.TecladoPanel = uipanel(app.calculadoraPanel);
            app.TecladoPanel.Title = 'Teclado';
            app.GridLayout = uigridlayout(app.TecladoPanel);
            app.VariableXButton = uibutton(app.GridLayout, 'push');
            app.VariableXButton.ButtonPushedFcn = createCallbackFcn(app, @VariableXButtonPushed, true);
            app.VariableXButton.Text = 'Variable X';
            app.LimpiarButton = uibutton(app.GridLayout, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            app.LimpTodoButton = uibutton(app.GridLayout, 'push');
            app.LimpTodoButton.Text = 'Limp. Todo';
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '+';
            app.Button_2 = uibutton(app.GridLayout, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '1';
            app.Button_3 = uibutton(app.GridLayout, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '2';
            app.Button_4 = uibutton(app.GridLayout, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '3';
            app.Button_5 = uibutton(app.GridLayout, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '-';
            app.Button_6 = uibutton(app.GridLayout, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '4';
            app.Button_7 = uibutton(app.GridLayout, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '5';
            app.Button_8 = uibutton(app.GridLayout, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '6';
            app.Button_9 = uibutton(app.GridLayout, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '*';
            app.Button_10 = uibutton(app.GridLayout, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '7';
            app.Button_11 = uibutton(app.GridLayout, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '8';
            app.Button_12 = uibutton(app.GridLayout, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '9';
            app.Button_13 = uibutton(app.GridLayout, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Text = '/';
            app.SenButton = uibutton(app.GridLayout, 'push');
            app.SenButton.ButtonPushedFcn = createCallbackFcn(app, @SenButtonPushed, true);
            app.SenButton.Text = 'Sen';
            app.CosButton = uibutton(app.GridLayout, 'push');
            app.CosButton.ButtonPushedFcn = createCallbackFcn(app, @CosButtonPushed, true);
            app.CosButton.Text = 'Cos';
            app.TanButton = uibutton(app.GridLayout, 'push');
            app.TanButton.ButtonPushedFcn = createCallbackFcn(app, @TanButtonPushed, true);
            app.TanButton.Text = 'Tan';
            app.Button_14 = uibutton(app.GridLayout, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_14.Text = '(';
            app.Button_15 = uibutton(app.GridLayout, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Text = ')';
            app.eButton = uibutton(app.GridLayout, 'push');
            app.eButton.ButtonPushedFcn = createCallbackFcn(app, @eButtonPushed, true);
            app.eButton.Text = 'e';
            app.lnButton = uibutton(app.GridLayout, 'push');
            app.lnButton.ButtonPushedFcn = createCallbackFcn(app, @lnButtonPushed, true);
            app.lnButton.Text = 'ln';
            app.RaiznButton = uibutton(app.GridLayout, 'push');
            app.RaiznButton.ButtonPushedFcn = createCallbackFcn(app, @RaiznButtonPushed, true);
            app.RaiznButton.Text = 'Raiz n';
            app.PotnButton = uibutton(app.GridLayout, 'push');
            app.PotnButton.ButtonPushedFcn = createCallbackFcn(app, @PotnButtonPushed, true);
            app.PotnButton.Text = 'Pot. n';
            app.Panel = uipanel(app.calculadoraPanel);
            app.Panel_2 = uipanel(app.Panel);
            app.IngreseFuncinLabel = uilabel(app.Panel_2);
            app.IngreseFuncinLabel.Text = 'Ingrese Función';
            app.FxEditFieldLabel = uilabel(app.Panel_2);
            app.FxEditFieldLabel.Text = 'F(x)=';
            app.Funcion = uieditfield(app.Panel_2, 'text');
            app.IntInferiorEditFieldLabel = uilabel(app.Panel_2);
            app.IntInferiorEditFieldLabel.Text = 'Int. Inferior';
            app.IntInferiorEditField = uieditfield(app.Panel_2, 'numeric');
            app.IntInferiorEditField.Value = -10;
            app.IntSuperiorEditFieldLabel = uilabel(app.Panel_2);
            app.IntSuperiorEditFieldLabel.Text = 'Int. Superior';
            app.IntSuperiorEditField = uieditfield(app.Panel_2, 'numeric');
            app.IntSuperiorEditField.Value = 10;
            app.Panel_3 = uipanel(app.Panel_2);
            app.GridLayout2 = uigridlayout(app.Panel_3);
            app.GraficarButton = uibutton(app.GridLayout2, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.LimpiarButton_2 = uibutton(app.GridLayout2, 'push');
            app.LimpiarButton_2.Text = 'Limpiar';
            app.Panel_4 = uipanel(app.Panel);
            app.MetAbiertosDropDownLabel = uilabel(app.Panel_4);
            app.MetAbiertosDropDownLabel.Text = 'Met. Abiertos';
            app.MetAbiertosDropDown = uidropdown(app.Panel_4);
            app.MetAbiertosDropDown.Items = {'', 'Punto Fijo', 'Newton-Raphson', 'Secante', 'Raic. Multiples'};
            app.MetAbiertosDropDown.Value = '';
            app.MetCerradosDropDownLabel = uilabel(app.Panel_4);
            app.MetCerradosDropDownLabel.Text = 'Met. Cerrados';
            app.MetCerradosDropDown = uidropdown(app.Panel_4);
            app.MetCerradosDropDown.Items = {'', 'Bisección', 'Regla Falsa'};
            app.MetCerradosDropDown.Value = '';
            app.MtodosNumricosLabel = uilabel(app.Panel_4);
            app.MtodosNumricosLabel.Text = 'Métodos Numéricos';
            app.LimInferiorEditFieldLabel = uilabel(app.Panel_4);
            app.LimInferiorEditFieldLabel.Text = 'Lim. Inferior';
            app.LimInferiorEditField = uieditfield(app.Panel_4, 'numeric');
            app.LimSuperiorEditFieldLabel = uilabel(app.Panel_4);
            app.LimSuperiorEditFieldLabel.Text = 'Lim. Superior';
            app.LimSuperiorEditField = uieditfield(app.Panel_4, 'numeric');
            app.ErrorTEditFieldLabel = uilabel(app.Panel_4);
            app.ErrorTEditFieldLabel.Text = 'Error T: ';
            app.ErrorTEditField = uieditfield(app.Panel_4, 'numeric');
            app.ErrorTEditField.Value = 0.001;
            app.ResolverButton = uibutton(app.Panel_4, 'push');
            app.ResolverButton.ButtonPushedFcn = createCallbackFcn(app, @ResolverButtonPushed, true);
            app.ResolverButton.Text = 'Resolver';
            app.SolucionLabel = uilabel(app.Panel_4);
            app.SolucionLabel.Text = 'Solucion';
            app.Solucion = uieditfield(app.Panel_4, 'text');
            app.Solucion.Editable = 'off';
            app.UIAxes = uiaxes(app.calculadoraPanel);
            title(app.UIAxes, 'Funcion:')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XAxisLocation = 'origin';
            app.UIAxes.YAxisLocation = 'origin';
            app.tableRes = uitable(app.calculadoraPanel);
            app.tableRes.ColumnName = {''};
            app.tableRes.RowName = {};
            app.tableRes.Tag = 'tableRes';
            app.Panel_5 = uipanel(app.calculadoraPanel);
            app.RutaEditFieldLabel = uilabel(app.Panel_5);
            app.RutaEditFieldLabel.Text = 'Ruta';
            app.RutaEditField = uieditfield(app.Panel_5, 'text');
            app.RutaEditField.Tag = 'rutaEdit_calculadora';
            app.NombreEditFieldLabel = uilabel(app.Panel_5);
            app.NombreEditFieldLabel.Text = 'Nombre';
            app.NombreEditField = uieditfield(app.Panel_5, 'text');
            app.NombreEditField.Tag = 'nombreEdit_calculadora';
            app.ExtensinButtonGroup = uibuttongroup(app.Panel_5);
            app.ExtensinButtonGroup.Title = 'Extensión';
            app.ExtensinButtonGroup.Tag = 'extensionSelect_calculadora';
            app.csvButton = uiradiobutton(app.ExtensinButtonGroup);
            app.csvButton.Text = '.csv';
            app.csvButton.Value = true;
            app.xlsxButton = uiradiobutton(app.ExtensinButtonGroup);
            app.xlsxButton.Text = '.xlsx';
            app.txtButton = uiradiobutton(app.ExtensinButtonGroup);
            app.txtButton.Text = '.txt';
            app.ExportarButton = uibutton(app.Panel_5, 'push');
            app.ExportarButton.ButtonPushedFcn = createCallbackFcn(app, @ExportarButtonPushed, true);
            app.ExportarButton.Tag = 'esportarB_calculadora';
            app.ExportarButton.Text = 'Exportar';
            app.ejerciciosPanel = uipanel(app.UIFigure);
            app.ejerciciosPanel.Visible = 'off';
            app.GridLayout4 = uigridlayout(app.ejerciciosPanel);
            app.TabGroup2 = uitabgroup(app.GridLayout4);
            app.TabGroup2.SelectionChangedFcn = createCallbackFcn(app, @TabGroup2SelectionChanged, true);
            app.MtododeBiseccinTab_2 = uitab(app.TabGroup2);
            app.MtododeBiseccinTab_2.SizeChangedFcn = createCallbackFcn(app, @MtododeBiseccinTab_2SizeChanged, true);
            app.MtododeBiseccinTab_2.Title = '        Método de Bisección        ';
            app.tituloBiseccion_ejercicios = uilabel(app.MtododeBiseccinTab_2);
            app.tituloBiseccion_ejercicios.Tag = 'titulo_menu1';
            app.tituloBiseccion_ejercicios.Text = 'Método cerrado Bisección ';
            app.Image11 = uiimage(app.MtododeBiseccinTab_2);
            app.Image11.ImageSource = '0001.jpg';
            app.Image12 = uiimage(app.MtododeBiseccinTab_2);
            app.Image12.ImageSource = '0002.jpg';
            app.Image13 = uiimage(app.MtododeBiseccinTab_2);
            app.Image13.ImageSource = '0003.jpg';
            app.Image14 = uiimage(app.MtododeBiseccinTab_2);
            app.Image14.ImageSource = '0004.jpg';
            app.MtododeRegulaFalsiTab_2 = uitab(app.TabGroup2);
            app.MtododeRegulaFalsiTab_2.Title = '        Método de Regula Falsi        ';
            app.tituloRegula_ejercicios = uilabel(app.MtododeRegulaFalsiTab_2);
            app.tituloRegula_ejercicios.Tag = 'titulo_menu1';
            app.tituloRegula_ejercicios.Text = 'Método cerrado Regula Falsi';
            app.Image15 = uiimage(app.MtododeRegulaFalsiTab_2);
            app.Image15.ImageSource = '0009.jpg';
            app.Image16 = uiimage(app.MtododeRegulaFalsiTab_2);
            app.Image16.ImageSource = '0008.jpg';
            app.Image17 = uiimage(app.MtododeRegulaFalsiTab_2);
            app.Image17.ImageSource = '0007.jpg';
            app.Image18 = uiimage(app.MtododeRegulaFalsiTab_2);
            app.Image18.ImageSource = '0006.jpg';
            app.Image19 = uiimage(app.MtododeRegulaFalsiTab_2);
            app.Image19.ImageSource = '0005.jpg';
            app.MtodoPuntoFijoTab_2 = uitab(app.TabGroup2);
            app.MtodoPuntoFijoTab_2.Title = '        Método Punto Fijo        ';
            app.tituloPunto_ejercicios = uilabel(app.MtodoPuntoFijoTab_2);
            app.tituloPunto_ejercicios.Tag = 'titulo_menu1';
            app.tituloPunto_ejercicios.Text = 'Método abierto Punto Fijo ';
            app.Image20 = uiimage(app.MtodoPuntoFijoTab_2);
            app.Image20.ImageSource = '0014.jpg';
            app.Image21 = uiimage(app.MtodoPuntoFijoTab_2);
            app.Image21.ImageSource = '0013.jpg';
            app.Image22 = uiimage(app.MtodoPuntoFijoTab_2);
            app.Image22.ImageSource = '0012.jpg';
            app.Image23 = uiimage(app.MtodoPuntoFijoTab_2);
            app.Image23.ImageSource = '0011.jpg';
            app.Image24 = uiimage(app.MtodoPuntoFijoTab_2);
            app.Image24.ImageSource = '0010.jpg';
            app.MtodoNewtonRaphsonTab_2 = uitab(app.TabGroup2);
            app.MtodoNewtonRaphsonTab_2.Title = '       Método Newton-Raphson      ';
            app.tituloNewton_ejercicios = uilabel(app.MtodoNewtonRaphsonTab_2);
            app.tituloNewton_ejercicios.Tag = 'titulo_menu1';
            app.tituloNewton_ejercicios.Text = 'Método abierto Newton-Raphson ';
            app.Image34 = uiimage(app.MtodoNewtonRaphsonTab_2);
            app.Image34.ImageSource = '0015-1.PNG';
            app.Image35 = uiimage(app.MtodoNewtonRaphsonTab_2);
            app.Image35.ImageSource = '0015.jpg';
            app.Image36 = uiimage(app.MtodoNewtonRaphsonTab_2);
            app.Image36.ImageSource = '0016.jpg';
            app.Image37 = uiimage(app.MtodoNewtonRaphsonTab_2);
            app.Image37.ImageSource = '0017.jpg';
            app.MtododelaSecanteTab_2 = uitab(app.TabGroup2);
            app.MtododelaSecanteTab_2.Title = '        Método de la Secante      ';
            app.tituloSecante_ejercicios = uilabel(app.MtododelaSecanteTab_2);
            app.tituloSecante_ejercicios.Tag = 'titulo_menu1';
            app.tituloSecante_ejercicios.Text = 'Método abierto de la Secante ';
            app.Image25 = uiimage(app.MtododelaSecanteTab_2);
            app.Image25.ImageSource = '0018.jpg';
            app.Image26 = uiimage(app.MtododelaSecanteTab_2);
            app.Image26.ImageSource = '0019.jpg';
            app.Image27 = uiimage(app.MtododelaSecanteTab_2);
            app.Image27.ImageSource = '0020.jpg';
            app.Image28 = uiimage(app.MtododelaSecanteTab_2);
            app.Image28.ImageSource = '0021.jpg';
            app.MtododeRacesMltiplesTab_2 = uitab(app.TabGroup2);
            app.MtododeRacesMltiplesTab_2.Title = '        Método de Raíces Múltiples          ';
            app.tituloRaices_ejercicios = uilabel(app.MtododeRacesMltiplesTab_2);
            app.tituloRaices_ejercicios.Tag = 'titulo_menu1';
            app.tituloRaices_ejercicios.Text = 'Método abierto Raíces Múltiples ';
            app.Image29 = uiimage(app.MtododeRacesMltiplesTab_2);
            app.Image29.ImageSource = '0022.jpg';
            app.Image30 = uiimage(app.MtododeRacesMltiplesTab_2);
            app.Image30.ImageSource = '0023.jpg';
            app.Image31 = uiimage(app.MtododeRacesMltiplesTab_2);
            app.Image31.ImageSource = '0024.jpg';
            app.Image32 = uiimage(app.MtododeRacesMltiplesTab_2);
            app.Image32.ImageSource = '0025.jpg';
            app.Image33 = uiimage(app.MtododeRacesMltiplesTab_2);
            app.Image33.ImageSource = '0026.jpg';
            app.MtodosCerradosLabel_2 = uilabel(app.GridLayout4);
            app.MtodosCerradosLabel_2.Text = 'Métodos Cerrados';
            app.MtodosAbiertosLabel_2 = uilabel(app.GridLayout4);
            app.MtodosAbiertosLabel_2.Text = 'Métodos Abiertos';
            app.evaluacionPanel = uipanel(app.UIFigure);
            app.evaluacionPanel.Visible = 'off';
            app.GridLayout5 = uigridlayout(app.evaluacionPanel);
            app.evaluacionHere = uipanel(app.GridLayout5);
            app.evaluacionHere.Tag = 'evaluacionHere';
            app.GridLayout6 = uigridlayout(app.evaluacionHere);
            app.evaluarB_evaluacion = uibutton(app.GridLayout5, 'push');
            app.evaluarB_evaluacion.ButtonPushedFcn = createCallbackFcn(app, @evaluarB_evaluacionButtonPushed, true);
            app.evaluarB_evaluacion.Tag = 'evaluarB_evaluacion';
            app.evaluarB_evaluacion.Text = 'Evaluar';
            app.notaEvaluacion = uilabel(app.GridLayout5);
            app.notaEvaluacion.Tag = 'notaEvaluacion';
            app.notaEvaluacion.Text = '';
            app.SaberMas = uipanel(app.UIFigure);
            app.SaberMas.Visible = 'off';
            app.SaberMas.SizeChangedFcn = createCallbackFcn(app, @SaberMasSizeChanged, true);
            app.TextArea_5Label = uilabel(app.SaberMas);
            app.TextArea_5Label.Text = 'Text Area';
            app.SOBRELAAPLICACINLabel = uilabel(app.SaberMas);
            app.SOBRELAAPLICACINLabel.Text = 'SOBRE LA APLICACIÓN';
            app.TextArea = uitextarea(app.SaberMas);
            app.TextArea.Value = {''; 'La aplicación “XXXXXX” es un programa concebido para la enseñanza, del tema de raíces de ecuaciones con los distintos métodos de solución que ofrecen los métodos numéricos.'; 'Es fácil aprender a usar este software, pero se asume que el usuario estará ya familiarizado con los términos, algunos conceptos básicos para entender los temas presentados en la parte de teoría.'};
            app.TextArea_2 = uitextarea(app.SaberMas);
            app.TextArea_2.Value = {' '; ' Sergio Yhoel Soncco Cahui, Correo: ssonccoc@unsa.edu.pe'; '  Jhonatan Acuña Huisacayna, Correo: jacuna@unsa.edu.pe'; '  Bryan Gallegos Batallanos, Correo: cgallegosb@unsa.edu.pe'; '  Frank Ccapa Usca, Correo: fccapau@unsa.edu.pe'; '  Luis Flores Bailon, Correo: lflorezb@unsa.edu.pe'; ''};
            app.CREADORESLabel = uilabel(app.SaberMas);
            app.CREADORESLabel.Text = 'CREADORES';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = raicesDeEcuaciones

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end